classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        errorstateEditField          matlab.ui.control.EditField
        errorstateEditFieldLabel     matlab.ui.control.Label
        enternumberEditField         matlab.ui.control.NumericEditField
        enternumberEditFieldLabel    matlab.ui.control.Label
        GetimageButton               matlab.ui.control.Button
        Below60happyAbove60sadLabel  matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GetimageButton
        function GetimageButtonPushed(app, event)
            %remove the labels of the plot
            xlabel(app.UIAxes,'');
            ylabel(app.UIAxes,'');
            
            % Get entered value
            value = app.enternumberEditField.Value;
            
            % Check if the entered value is a number
            if isnumeric(value) && ~isnan(value)
                % Display sad or happy face based on the entered value
                if value > 60
                    app.displaySadFace();
                else
                    app.displayHappyFace();
                end

                % Clear error message
                app.errorstateEditField.Value = 'correct';
            else
                % Display error message for non-numeric input
                app.errorstateEditField.Value = 'Error:non-numeric input';
            end
        end

        % Function to display a sad face
        function displaySadFace(app)
            sadImag = imread("sad.jpg");
             imshow(sadImag,'Parent',app.UIAxes);
             title(app.UIAxes,'Sad!');
        end

        % Function to display a happy face
        function displayHappyFace(app)
             happyImag = imread("happy.jpg");
             imshow(happyImag,'Parent',app.UIAxes);
             title(app.UIAxes,'Happy!');
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Below60happyAbove60sadLabel = uilabel(app.UIFigure);
            app.Below60happyAbove60sadLabel.Text = {'Below 60-> happy :)'; 'Above 60 -> sad :('};
            app.GetimageButton = uibutton(app.UIFigure, 'push');
            app.GetimageButton.ButtonPushedFcn = createCallbackFcn(app, @GetimageButtonPushed, true);
            app.GetimageButton.Text = 'Get image';
            app.enternumberEditFieldLabel = uilabel(app.UIFigure);
            app.enternumberEditFieldLabel.Text = 'enter number';
            app.enternumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.errorstateEditFieldLabel = uilabel(app.UIFigure);
            app.errorstateEditFieldLabel.Text = 'error state';
            app.errorstateEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end